classdef EventDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure                   matlab.ui.Figure
        preprocessingPanel           matlab.ui.container.Panel
        preprocessingSignalComputerVariablesTable  matlab.ui.control.Table
        preprocessingSignalComputerList  matlab.ui.control.ListBox
        preprocessingSignalsList     matlab.ui.control.ListBox
        eventDetectionPanel          matlab.ui.container.Panel
        eventDetectorVariablesTable  matlab.ui.control.Table
        eventDetectorList            matlab.ui.control.ListBox
        groupingPanel                matlab.ui.container.Panel
        labelGroupingsList           matlab.ui.control.ListBox
        groupingTable                matlab.ui.control.Table
        dataPanel                    matlab.ui.container.Panel
        filesList                    matlab.ui.control.ListBox
        selectAllFilesCheckBox       matlab.ui.control.CheckBox
        filesResultTable             matlab.ui.control.Table
        classesResultTable           matlab.ui.control.Table
        EventMatchingToleranceLabel  matlab.ui.control.Label
        toleranceTextBox             matlab.ui.control.NumericEditField
        executeButton                matlab.ui.control.Button
        saveEventsButton             matlab.ui.control.Button
        clearCacheButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        %data
        dataLoader;
        fileNames;
        annotations;
        arcChain;
        preprocessingChain;
        fileLoader;
        fileLoaderPreprocessing;
        
        %detection
        eventDetectorConfigurator;
        eventsPerFile;
        
        %energies
        energiesPerFile;
        
        %labeling strategies
        groupingConfigurator;
        currentLabelGrouping;
        
        %results
        resultsComputer;
        resultsPerFile;
        
        %ui plotting
        scrollPanel;
        eventsPlotter = [];
        preprocessingConfigurator;
        figureHandle;
        uiHandles;
        axesHandles;
        
    end
    
    properties (Access = public, Constant)
        kMaxFileNameLength = 12;
    end
    
    methods (Access = private)
        
        function idxs = getSelectedFileIdxs(app)
            selectedIdxsStr = app.filesList.Value;
            [~,idxs] = ismember(selectedIdxsStr,app.filesList.Items);
            idxs = sort(idxs);
        end
        function selectedFiles = getSelectedFiles(app)
            selectedIdxs = app.getSelectedFileIdxs();
            selectedFiles = app.filesList.Items(selectedIdxs);
        end
        
        function tolerance = getTolerance(app)
            tolerance = app.toleranceTextBox.Value;
        end
        
        function showResultTables(app)
            app.filesResultTable.Visible = true;
            app.classesResultTable.Visible = true;
        end
        
        function hideResultTables(app)
            app.filesResultTable.Visible = false;
            app.classesResultTable.Visible = false;
        end
        
        function updateFilesTable(app,selectedFileIdxs)
            
            nRows = length(app.resultsPerFile);
            resultsTable = cell(nRows+1,3);
            
            for i = 1 : nRows
                fileIdx = selectedFileIdxs(i);
                fileName = app.fileNames{fileIdx};
                fileResults = app.resultsPerFile(i);
                
                resultsTable{i,1} = fileName;
                resultsTable{i,2} = sprintf('%.2f%%',100 * fileResults.recall);
                resultsTable{i,3} = sprintf('x%.2f',fileResults.FPEventRate);
                resultsTable{i,4} = sprintf('%.2f%%',100 * fileResults.f1Score);
            end
            
            aggregatedFilesResults = DetectionAggregatedResults.DetectionAggregatedResultsWithDetectionResults(app.resultsPerFile);
            resultsTable{nRows+1,1} = 'Total';
            resultsTable{nRows+1,2} = sprintf('%.2f%%',100 * aggregatedFilesResults.nGoodEventsDetected / aggregatedFilesResults.nGoodEvents);
            resultsTable{nRows+1,3} = sprintf('x%.2f%',aggregatedFilesResults.nBadEventsDetected / aggregatedFilesResults.nGoodEvents);
            resultsTable{nRows+1,4} = sprintf('%.2f%%',100 * mean([fileResults.f1Score]));
            app.filesResultTable.Data = resultsTable;
        end
        
        function updateClassResultTable(app,classDetectionStatistics)
            
            isPositiveClass = app.resultsComputer.positiveLabels;
            
            nGoodEventsDetectedPerClass = classDetectionStatistics.nGoodEventsDetectedPerClass;
            nTotalGoodEventsPerClass = classDetectionStatistics.nGoodEventsPerClass;
            
            nRows = sum(isPositiveClass);
            resultsTable = cell(nRows+1,3);
            
            rowIdx = 1;
            for i = 1 : length(isPositiveClass)
                if isPositiveClass(i)
                    classStr = app.currentLabelGrouping.classNames{i};
                    detectedPerClass = nGoodEventsDetectedPerClass(i);
                    if nTotalGoodEventsPerClass(i) == 0
                        detectionRate = 0;
                    else
                        detectionRate = 100 * detectedPerClass / nTotalGoodEventsPerClass(i);
                    end
                    resultsTable{rowIdx,1} = classStr;
                    resultsTable{rowIdx,2} = detectedPerClass;
                    resultsTable{rowIdx,3} = sprintf('%.2f%%',detectionRate);
                    rowIdx = rowIdx + 1;
                end
            end
            
            totalGoodEventsDetected = sum(nGoodEventsDetectedPerClass);
            totalGoodEvents = sum(nTotalGoodEventsPerClass);
            detectionRate = 100 * totalGoodEventsDetected / totalGoodEvents;
            resultsTable{nRows+1,1} = 'Total';
            resultsTable{nRows+1,2} = totalGoodEventsDetected;
            resultsTable{rowIdx,3} = sprintf('%.2f%%',detectionRate);
            
            app.classesResultTable.Data = resultsTable;
        end
        
        %methods
        function buildChainFromUI(app)
            app.fileLoader = FileLoader();
            
            labelGrouping = app.groupingConfigurator.getCurrentLabelGrouping();
            segmentsMapper = SegmentsClassToLabelMapper(labelGrouping);
            
            preprocessor = app.preprocessingConfigurator.createSignalComputerWithUIParameters();
            eventDetector = app.eventDetectorConfigurator.createComputerWithUIParameters();
            
            app.arcChain = Computer.ComputerWithSequence({app.fileLoader,...
                PropertyGetter('data'),preprocessor,eventDetector,...
                EventsLabeler(),segmentsMapper});
        end
        
        function buildPreprocessingChainFromUI(app)
            app.fileLoaderPreprocessing = FileLoader();
            
            preprocessor = app.preprocessingConfigurator.createSignalComputerWithUIParameters();
            app.preprocessingChain = Computer.ComputerWithSequence({app.fileLoaderPreprocessing,...
                PropertyGetter('data'),preprocessor});
        end
        
        function computeDetectionResults(app,tolerance,selectedFileIdxs)
            if ~isempty(app.eventsPerFile) && ~isempty(app.annotations)
                app.resultsComputer.tolerance = uint32(tolerance);
                app.resultsComputer.labelGrouping = app.groupingConfigurator.getCurrentLabelGrouping();
                annotation = app.annotations(selectedFileIdxs);
                app.resultsPerFile = app.resultsComputer.computeDetectionResults(app.eventsPerFile,annotation);
            end
        end
        
        function fileIdx = getCurrentFileIdx(app)
            fileIdxStr = app.visualizationFilesList.Value;
            [~,fileIdx] = ismember(fileIdxStr,app.visualizationFilesList.Items);
        end
        
        function computeDetectionStatistics(app,selectedFileIdxs,selectedLabels)
            nClasses = length(selectedLabels);
            aggregatedClassResults = DetectionAggregatedResultsPerClass.computeAggregatedClassStatistics(app.resultsPerFile,nClasses);
            
            app.showResultTables();
            app.updateFilesTable(selectedFileIdxs);
            app.updateClassResultTable(aggregatedClassResults);
        end
        
        function saveEvents(app)
            if ~isempty(app.eventsPerFile)
                currentFileIdx = app.getCurrentFileIdx();
                if ~isempty(currentFileIdx)
                    events = app.eventsPerFile{currentFileIdx};
                    app.dataLoader.saveEvents(events,Constants.kDetectedEventsFileName);
                end
            end
        end
        
        function loadFilesList(app)
            app.filesList.Items = app.fileNames;
            app.filesList.Value = app.filesList.Items;
        end
        
        function executeChain(app, selectedFileIdxs)
            selectedFileNames = app.fileNames(selectedFileIdxs);
            nFiles = length(selectedFileIdxs);
            app.eventsPerFile = cell(1,nFiles);
            
            for i = 1 : nFiles
                
                app.fileLoader.fileName = selectedFileNames{i};
                
                selectedFileIdx = selectedFileIdxs(i);
                annotation = app.annotations(selectedFileIdx);
                
                Computer.SetSharedContextVariable(Constants.kSharedVariableCurrentAnnotationFile, annotation);
                
                app.eventsPerFile{i} = Computer.ExecuteChain(app.arcChain,'start',true);
            end
        end
        
        function executePreprocessingChain(app, selectedFileIdxs)
            selectedFileNames = app.fileNames(selectedFileIdxs);
            
            for i = 1 : length(selectedFileIdxs)
                app.fileLoaderPreprocessing.fileName = selectedFileNames{i};
                app.energiesPerFile{i} = Computer.ExecuteChain(app.preprocessingChain,'start',true);
            end
        end
        
        function createEventsPlotter(app,selectedFileIdxs)
            
            selectedFiles = app.filesList.Items(selectedFileIdxs);
            
            if ~isempty(app.eventsPlotter)
                app.eventsPlotter.close();
            end
            
            currentWindowPosition = app.mainFigure.OuterPosition;
            positionX = currentWindowPosition(1) + currentWindowPosition(3);
            windowPosition = [positionX, currentWindowPosition(2)];
            
            app.eventsPlotter = DetectionEventsPlotter(app,app.energiesPerFile,app.resultsPerFile,selectedFiles,windowPosition);
            app.eventsPlotter.labelGrouping = app.currentLabelGrouping;
        end
    end
    
    methods (Access = public)
        
        function handleEventsPlotterWindowClosed(app)
            delete(app.eventsPlotter);
            app.eventsPlotter = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.mainFigure.Visible = false;
            close all;
            clear ClassesMap;
            clear Cache;
            movegui(app.mainFigure,'center');
            app.mainFigure.Visible = true;
            
            app.dataLoader = DataLoader();
            app.resultsComputer = DetectionResultsComputer();
            
            %load data
            app.annotations = app.dataLoader.loadAllAnnotations();
            
            app.fileNames = Helper.listDataFiles();
            app.loadFilesList();
                        
            %load computers
            eventDetectors = {SimplePeakDetector, MatlabPeakDetector};
            
            app.eventDetectorConfigurator = ComputerConfigurator(...
                eventDetectors,...
                app.eventDetectorList...
                ,app.eventDetectorVariablesTable);
            
            signalComputers = Palette.PreprocessingComputers();
            
            app.preprocessingConfigurator = PreprocessingConfigurator(...
                signalComputers,...
                app.preprocessingSignalsList,...
                app.preprocessingSignalComputerList,...
                app.preprocessingSignalComputerVariablesTable);
            
            app.preprocessingConfigurator.setDefaultColumnNames();
            app.preprocessingSignalsList.Value = app.preprocessingSignalsList.Items{1};
            
            labelGroupings = app.dataLoader.loadAllLabelGroupings();
            
            app.groupingConfigurator = GroupingConfigurator(...
                labelGroupings,...
                app.labelGroupingsList,...
                app.groupingTable);
        end

        % Button pushed function: executeButton
        function executeButtonPushed(app, event)
            
            selectedFileIdxs = app.getSelectedFileIdxs();
            if ~isempty(selectedFileIdxs)
                                
                app.currentLabelGrouping = app.groupingConfigurator.getCurrentLabelGrouping();
                app.resultsComputer.labelGrouping = app.currentLabelGrouping;
                
                selectedLabels = app.groupingConfigurator.getSelectedLabels();
                
                app.buildChainFromUI();
                
                try
                    app.executeChain(selectedFileIdxs);
                catch exception
                    warning(Constants.kAlgorithmExecutionFailedWarning);
                    msgText = getReport(exception);
                    warning(msgText);
                end
                
                if ~isempty(app.eventsPerFile)
                    
                    app.buildPreprocessingChainFromUI();
                    selectedFileIdxs = app.getSelectedFileIdxs();
                    app.executePreprocessingChain(selectedFileIdxs);
                end
                
                tolerance = app.getTolerance();
                app.resultsComputer.positiveLabels = selectedLabels;
                app.computeDetectionResults(tolerance,selectedFileIdxs);
                app.computeDetectionStatistics(selectedFileIdxs,selectedLabels);
                
                app.createEventsPlotter(selectedFileIdxs);
            end
        end

        % Button pushed function: saveEventsButton
        function saveEventsButtonPushed(app, event)
            app.saveEvents();
        end

        % Value changed function: selectAllFilesCheckBox
        function selectAllFilesCheckBoxValueChanged(app, event)
            value = app.selectAllFilesCheckBox.Value;
            if value == true
                app.filesList.Value = app.filesList.Items;
            end
        end

        % Button pushed function: clearCacheButton
        function clearCacheButtonPushed(app, event)
            Cache.ClearAllCacheFiles();
            clear Cache;
        end

        % Close request function: mainFigure
        function mainFigureCloseRequest(app, event)
           if ~isempty(app.eventsPlotter)
               app.eventsPlotter.delegate = [];
               app.eventsPlotter.close();
           end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure('Visible', 'off');
            app.mainFigure.Name = 'Event Detection App';
            app.mainFigure.CloseRequestFcn = createCallbackFcn(app, @mainFigureCloseRequest, true);
            app.preprocessingPanel = uipanel(app.mainFigure);
            app.preprocessingPanel.Title = 'Preprocessing';
            app.preprocessingSignalComputerVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingSignalComputerVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.preprocessingSignalComputerVariablesTable.RowName = {};
            app.preprocessingSignalComputerVariablesTable.ColumnEditable = [false true];
            app.preprocessingSignalComputerList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalComputerList.Items = {};
            app.preprocessingSignalComputerList.Value = {};
            app.preprocessingSignalsList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalsList.Items = {};
            app.preprocessingSignalsList.Multiselect = 'on';
            app.preprocessingSignalsList.Value = {};
            app.eventDetectionPanel = uipanel(app.mainFigure);
            app.eventDetectionPanel.Title = 'Event Detection';
            app.eventDetectorVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectorVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectorVariablesTable.RowName = {};
            app.eventDetectorVariablesTable.ColumnEditable = [false true];
            app.eventDetectorList = uilistbox(app.eventDetectionPanel);
            app.eventDetectorList.Items = {};
            app.eventDetectorList.Value = {};
            app.groupingPanel = uipanel(app.mainFigure);
            app.groupingPanel.Title = 'Label Grouping';
            app.labelGroupingsList = uilistbox(app.groupingPanel);
            app.labelGroupingsList.Items = {};
            app.labelGroupingsList.Value = {};
            app.groupingTable = uitable(app.groupingPanel);
            app.groupingTable.ColumnName = {'TP Classes'; ''};
            app.groupingTable.RowName = {};
            app.groupingTable.ColumnEditable = [false true];
            app.dataPanel = uipanel(app.mainFigure);
            app.dataPanel.Title = 'Data';
            app.filesList = uilistbox(app.dataPanel);
            app.filesList.Items = {};
            app.filesList.Multiselect = 'on';
            app.filesList.Value = {};
            app.selectAllFilesCheckBox = uicheckbox(app.dataPanel);
            app.selectAllFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectAllFilesCheckBoxValueChanged, true);
            app.selectAllFilesCheckBox.Text = 'select all';
            app.filesResultTable = uitable(app.mainFigure);
            app.filesResultTable.ColumnName = {'File'; 'TP (%)'; 'FP (rate)'; 'F1-S (%)'};
            app.filesResultTable.RowName = {};
            app.classesResultTable = uitable(app.mainFigure);
            app.classesResultTable.ColumnName = {'Class'; 'Detected (#)'; 'Detected (%)'};
            app.classesResultTable.RowName = {};
            app.EventMatchingToleranceLabel = uilabel(app.mainFigure);
            app.EventMatchingToleranceLabel.Text = {'Event Matching'; 'Tolerance'};
            app.toleranceTextBox = uieditfield(app.mainFigure, 'numeric');
            app.toleranceTextBox.Value = 10;
            app.executeButton = uibutton(app.mainFigure, 'push');
            app.executeButton.ButtonPushedFcn = createCallbackFcn(app, @executeButtonPushed, true);
            app.executeButton.Text = 'Execute';
            app.saveEventsButton = uibutton(app.mainFigure, 'push');
            app.saveEventsButton.ButtonPushedFcn = createCallbackFcn(app, @saveEventsButtonPushed, true);
            app.saveEventsButton.Text = 'Export';
            app.clearCacheButton = uibutton(app.mainFigure, 'push');
            app.clearCacheButton.ButtonPushedFcn = createCallbackFcn(app, @clearCacheButtonPushed, true);
            app.clearCacheButton.Text = 'Clear Cache';
            % Show the figure after all components are created
            app.mainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EventDetectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end